classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ExportButton        matlab.ui.control.Button
        LoadImageButton     matlab.ui.control.Button
        STDSlider           matlab.ui.control.Slider
        STDSliderLabel      matlab.ui.control.Label
        APPLYButton         matlab.ui.control.Button
        Input4EditField     matlab.ui.control.NumericEditField
        Input4Label         matlab.ui.control.Label
        Input3EditField     matlab.ui.control.NumericEditField
        Input3Label         matlab.ui.control.Label
        Input2EditField     matlab.ui.control.NumericEditField
        Input2Label         matlab.ui.control.Label
        Input1EditField     matlab.ui.control.NumericEditField
        Input1Label         matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        OutputImageLabel    matlab.ui.control.Label
        OriginalImageLabel  matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           global ImageFile;
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imshow(ImageFile, 'Parent', app.UIAxes);
        end

        % Value changed function: Input1EditField
        function Input1EditFieldValueChanged(app, event)
            global M;
            M = app.Input1EditField.Value;
            
        end

        % Value changed function: Input2EditField
        function Input2EditFieldValueChanged(app, event)
            global N;
            N = app.Input2EditField.Value;
        end

        % Value changed function: Input3EditField
        function Input3EditFieldValueChanged(app, event)
            global a;
            a = app.Input3EditField.Value;

        end

        % Value changed function: Input4EditField
        function Input4EditFieldValueChanged(app, event)
            global b;
            b = app.Input4EditField.Value;
            
        end

        % Value changed function: STDSlider
        function STDSliderValueChanged(app, event)
            global std;
            std = app.STDSlider.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global choice;
            choice = app.DropDown.Value;
            
            if isequal(choice, 'Histogram Equalization')
                app.Input1EditField.Visible = 'off';
                app.Input1Label.Visible = 'off';

                app.Input2EditField.Visible = 'off';
                app.Input2Label.Visible = "off";

                app.Input3EditField.Visible = 'off';
                app.Input3Label.Visible ="off";

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";


            elseif isequal(choice, 'Mean (Average) Filtering ')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Kernel Size';

                app.Input2EditField.Visible = 'off';
                app.Input2Label.Visible = "off";

                app.Input3EditField.Visible = 'off';
                app.Input3Label.Visible ="off";

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";


            elseif isequal(choice, 'Median Filtering ')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Kernel Size';

                app.Input2EditField.Visible = 'off';
                app.Input2Label.Visible = "off";

                app.Input3EditField.Visible = 'off';
                app.Input3Label.Visible ="off";

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Adaptive Median Filtering')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'MAximum Window Size';

                app.Input2EditField.Visible = 'off';
                app.Input2Label.Visible = "off";

                app.Input3EditField.Visible = 'off';
                app.Input3Label.Visible ="off";

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";


            elseif isequal(choice, 'Gaussian Filtering (Smoothing) ')
                app.Input1EditField.Visible = 'off';
                app.Input1Label.Visible = 'off';

                app.Input2EditField.Visible = 'off';
                app.Input2Label.Visible = "off";

                app.Input3EditField.Visible = 'off';
                app.Input3Label.Visible ="off";

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'on'; 
                app.STDSliderLabel.Visible = "on";


            elseif isequal(choice, 'Additive Uniform Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Uniform random Interval Min:';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Uniform random Interval Max:';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Additive Gaussian Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Mean: ';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Standart Deviation';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";


            elseif isequal(choice, 'Additive Salt&Pepper Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Amplitude 0 with probability: ';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Amplitude 1 with probability: ';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Additive LogNormal Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Offset: ';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Shape: ';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Additive Rayleigh Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Parameter 1: ';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Parameter 2: ';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Additive Exponential Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Exponential random numbers with parameter:';

                app.Input4EditField.Visible = 'off';
                app.Input4Label.Visible = "off";

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            elseif isequal(choice, 'Additive Erlang Noise')
                app.Input1EditField.Visible = 'on';
                app.Input1Label.Visible = 'on';
                app.Input1Label.Text = 'Size: M';

                app.Input2EditField.Visible = 'on';
                app.Input2Label.Visible = "on";
                app.Input2Label.Text = 'Size: N';

                app.Input3EditField.Visible = 'on';
                app.Input3Label.Visible ="on";
                app.Input3Label.Text = 'Erlang random numbers with parameter 1: ';

                app.Input4EditField.Visible = 'on';
                app.Input4Label.Visible = "on";
                app.Input4Label.Text = 'Erlang random numbers with parameter 2: ';

                app.STDSlider.Visible = 'off'; 
                app.STDSliderLabel.Visible = "off";

            end
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            global ImageFile;
            global choice;
            global R;

            global output_image;

            function outputImage = medianFilter(inputImage, filterSize)
                
                % Get the dimensions of the input image
                [rows, cols] = size(inputImage);
                
                % Initialize the output image with zeros
                outputImage = zeros(rows, cols);
                
                % Calculate the half-size of the filter
                halfSize = floor(filterSize / 2);
                
                for i = 1:rows
                    for j = 1:cols
                        % Extract the neighborhood of the current pixel
                        neighborhood = inputImage(max(i - halfSize, 1):min(i + halfSize, rows), ...
                                                  max(j - halfSize, 1):min(j + halfSize, cols));
                        
                        % Apply median filtering to the neighborhood
                        outputImage(i, j) = median(neighborhood(:));
                    end
                end
            
                % it didn't work when i didnot the data type convertion
                outputImage = uint8(outputImage);
            end
            function outputImage = meanFilter(inputImage, kernelSize)
                [rows, cols] = size(inputImage);
                outputImage = zeros(rows, cols);
            
                % Ensure the kernel size is odd
                if mod(kernelSize, 2) == 0
                    error('Kernel size must be odd.');
                end
            
                halfKernel = (kernelSize - 1) / 2;
            
                for i = 1:rows
                    for j = 1:cols
                        % Get the neighborhood
                        neighborhood = inputImage(max(1, i - halfKernel):min(rows, i + halfKernel), ...
                                                  max(1, j - halfKernel):min(cols, j + halfKernel));
            
                        % Compute the mean of the neighborhood
                        outputImage(i, j) = mean(neighborhood(:));
                    end
                end
            end
            function outputImage = adaptiveMedianFilter(inputImage, maxWindowSize)
                [rows, cols] = size(inputImage);
                outputImage = zeros(rows, cols);
            
                for i = 1:rows
                    for j = 1:cols
                        % Get the current pixel and its neighborhood
                        pixelValue = inputImage(i, j);
                        windowSize = 3; % Initial window size
            
                        while windowSize <= maxWindowSize
                            % Get the neighborhood
                            neighborhood = inputImage(max(1, i - (windowSize-1)/2):min(rows, i + (windowSize-1)/2), ...
                                                      max(1, j - (windowSize-1)/2):min(cols, j + (windowSize-1)/2));
            
                            % Check if the pixel value is the median within the neighborhood
                            if pixelValue == median(neighborhood(:))
                                outputImage(i, j) = pixelValue;
                                break;
                            else
                                % Increase the window size and try again
                                windowSize = windowSize + 2;
                            end
                        end
                    end
                end
            end
            function sonuc = GaussianKernel(std)

                size_number= std*6;
                
                %check the number
                %if it is even, add1
                if mod(size_number,2) ==0
                    size_number = size_number + 1;
                end
                
                [X, Y] = meshgrid(-(size_number-1)/2:(size_number-1)/2, -(size_number-1)/2:(size_number-1)/2);
                
                firstterm=1/(2*pi*std^2);
                secondterm=exp(-1*(X.^2+Y.^2)/(2*std^2));
                
                sonuc=firstterm*secondterm;
                
                % Normalize the kernel
                sonuc = sonuc / sum(sonuc(:));
                

            end
            function R = imnoise2(type, M, N, a, b)
                %IMNOISE2 Generates an array of random numbers with specified PDF.
                %   R = IMNOISE2(TYPE, M, N, A, B) generates an array, R, of size
                %   M-by-N, whose elements are random numbers of the specified TYPE
                %   with parameters A and B. If only TYPE is included in the
                %   input argument list, a  single random number of the specified
                %   TYPE and default parameters shown below is generated. If only
                %   TYPE, M, and N are provided, the default parameters shown below
                %   are used.  If M = N = 1, IMNOISE2 generates a single random
                %   number of the specified TYPE and parameters A and B.
                %
                %   Valid values for TYPE and parameters A and B are:
                % 
                %   'uniform'       Uniform random numbers in the interval (A, B).
                %                   The default values are (0, 1).  
                %   'gaussian'      Gaussian random numbers with mean A and standard
                %                   deviation B. The default values are A = 0, B = 1.
                %   'salt & pepper' Salt and pepper numbers of amplitude 0 with
                %                   probability Pa = A, and amplitude 1 with
                %                   probability Pb = B. The default values are Pa =
                %                   Pb = A = B = 0.05.  Note that the noise has
                %                   values 0 (with probability Pa = A) and 1 (with
                %                   probability Pb = B), so scaling is necessary if
                %                   values other than 0 and 1 are required. The noise
                %                   matrix R is assigned three values. If R(x, y) =
                %                   0, the noise at (x, y) is pepper (black).  If
                %                   R(x, y) = 1, the noise at (x, y) is salt
                %                   (white). If R(x, y) = 0.5, there is no noise
                %                   assigned to coordinates (x, y). 
                %   'lognormal'     Lognormal numbers with offset A and shape
                %                   parameter B. The defaults are A = 1 and B =
                %                   0.25.
                %   'rayleigh'      Rayleigh noise with parameters A and B. The
                %                   default values are A = 0 and B = 1. 
                %   'exponential'   Exponential random numbers with parameter A.  The
                %                   default is A = 1.
                %   'erlang'        Erlang (gamma) random numbers with parameters A
                %                   and B.  B must be a positive integer. The
                %                   defaults are A = 2 and B = 5.  Erlang random
                %                   numbers are approximated as the sum of B
                %                   exponential random numbers.
                
                %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
                %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
                %   $Revision: 1.5 $  $Date: 2003/10/12 23:37:29 $
                
                % Set default values.
                if nargin == 1
                   a = 0; b = 1;
                   M = 1; N = 1;
                elseif nargin == 3
                   a = 0; b = 1;
                end
                
                % Begin processing. Use lower(type) to protect against input being
                % capitalized. 
                switch lower(type)
                case 'uniform'
                   R = a + (b - a)*rand(M, N);
                case 'gaussian'
                   R = a + b*randn(M, N);
                case 'salt & pepper'
                   if nargin <= 3
                      a = 0.05; b = 0.05;
                   end
                   % Check to make sure that Pa + Pb is not > 1.
                   if (a + b) > 1
                      error('The sum Pa + Pb must not exceed 1.')
                   end
                   R(1:M, 1:N) = 0.5;
                   % Generate an M-by-N array of uniformly-distributed random numbers
                   % in the range (0, 1). Then, Pa*(M*N) of them will have values <=
                   % a. The coordinates of these points we call 0 (pepper
                   % noise). Similarly, Pb*(M*N) points will have values in the range
                   % > a & <= (a + b).  These we call 1 (salt noise). 
                   X = rand(M, N);
                   c = find(X <= a);
                   R(c) = 0;
                   u = a + b;
                   c = find(X > a & X <= u);
                   R(c) = 1;
                case 'lognormal'
                   if nargin <= 3
                      a = 1; b = 0.25;
                   end
                   R = a*exp(b*randn(M, N));
                case 'rayleigh'
                   R = a + (-b*log(1 - rand(M, N))).^0.5;
                case 'exponential'
                   if nargin <= 3
                      a = 1;
                   end
                   if a <= 0
                      error('Parameter a must be positive for exponential type.')
                   end
                   k = -1/a;
                   R = k*log(1 - rand(M, N));
                case 'erlang'
                   if nargin <= 3
                      a = 2; b = 5;
                   end
                   if (b ~= round(b) | b <= 0)
                      error('Param b must be a positive integer for Erlang.')
                   end
                   k = -1/a;
                   R = zeros(M, N);
                   for j = 1:b         
                      R = R + k*log(1 - rand(M, N));
                   end
                otherwise
                   error('Unknown distribution type.')
                end
                end


            if isequal(choice, 'Histogram Equalization')
                output_image = ImageFile;

            elseif isequal(choice, 'Mean (Average) Filtering ')
                output_image = meanFilter(ImageFile, app.Input1EditField.Value);

            elseif isequal(choice, 'Median Filtering ')
                output_image = medianFilter(ImageFile, app.Input1EditField.Value);

            elseif isequal(choice, 'Adaptive Median Filtering')
                output_image = adaptiveMedianFilter(ImageFile, app.Input1EditField.Value);

            elseif isequal(choice, 'Gaussian Filtering (Smoothing) ')
                 R = GaussianKernel(app.STDSlider.Value);
                 output_image = imfilter(ImageFile, R, 'conv');
                
            elseif isequal(choice, 'Additive Uniform Noise')
                R = imnoise2('uniform', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);
                 
            elseif isequal(choice, 'Additive Gaussian Noise')
                R = imnoise2('gaussian', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            elseif isequal(choice, 'Additive Salt&Pepper Noise')
                R = imnoise2('salt & pepper', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            elseif isequal(choice, 'Additive LogNormal Noise')
                R = imnoise2('lognormal', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            elseif isequal(choice, 'Additive Rayleigh Noise')
                R = imnoise2('rayleigh', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            elseif isequal(choice, 'Additive Exponential Noise')
                R = imnoise2('exponential', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            elseif isequal(choice, 'Additive Erlang Noise')
                R = imnoise2('erlang', app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value, app.Input4EditField.Value);
                output_image = double(ImageFile) + R;
                output_image = uint8(output_image);

            end

            imshow(output_image, 'Parent', app.UIAxes2);          

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global output_image;

            function exportImage(filename)
                % filename, dışa aktarılacak dosyanın adını ve uzantısını içerir
                imwrite(output_image, filename);
            end

            exportImage('exported_image.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Visible = 'off';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Select', 'Histogram Equalization', 'Mean (Average) Filtering ', 'Median Filtering ', 'Adaptive Median Filtering', 'Gaussian Filtering (Smoothing) ', 'Additive Uniform Noise', 'Additive Gaussian Noise', 'Additive Salt&Pepper Noise', 'Additive LogNormal Noise', 'Additive Rayleigh Noise', 'Additive Exponential Noise', 'Additive Erlang Noise'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Select';
            app.Input1Label = uilabel(app.UIFigure);
            app.Input1Label.Text = 'Input 1:';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input1EditField.ValueChangedFcn = createCallbackFcn(app, @Input1EditFieldValueChanged, true);
            app.Input1EditField.Placeholder = '0';
            app.Input2Label = uilabel(app.UIFigure);
            app.Input2Label.Text = 'Input 2:';
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input2EditField.ValueChangedFcn = createCallbackFcn(app, @Input2EditFieldValueChanged, true);
            app.Input3Label = uilabel(app.UIFigure);
            app.Input3Label.Text = 'Input 3:';
            app.Input3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input3EditField.ValueChangedFcn = createCallbackFcn(app, @Input3EditFieldValueChanged, true);
            app.Input4Label = uilabel(app.UIFigure);
            app.Input4Label.Text = 'Input 4:';
            app.Input4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input4EditField.ValueChangedFcn = createCallbackFcn(app, @Input4EditFieldValueChanged, true);
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.STDSliderLabel = uilabel(app.UIFigure);
            app.STDSliderLabel.Text = 'STD';
            app.STDSlider = uislider(app.UIFigure);
            app.STDSlider.Limits = [0 10];
            app.STDSlider.ValueChangedFcn = createCallbackFcn(app, @STDSliderValueChanged, true);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end